classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        originalButton  matlab.ui.control.Button
        computeButton   matlab.ui.control.Button
        loadButton      matlab.ui.control.Button
        UIAxes3         matlab.ui.control.UIAxes
        UIAxes2         matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function load(app, event)
            img1=imread('./logo_csse.png');
            imshow(img1, 'Parent', app.UIAxes);
        end

        % Button pushed function: computeButton
        function compute(app, event)
            img1=imread('./logo_csse.png');
            
            % compute image 
            imgray = rgb2gray(img1); 
            BW = imbinarize(imgray); 
            % plot images imshow(BW*255);
            imshow(BW, 'Parent', app.UIAxes2);
        end

        % Button pushed function: originalButton
        function original(app, event)
 

    img1 = imread('./logo_csse.png');
    
    % 画像サイズの取得
    [imageHeight, ~, ~] = size(img1);  % 不要な変数を削除
    
    % 縞模様の幅を設定
    stripeWidth = 10;
    
    % 縞模様を画像に適用
    for row = 1:stripeWidth*2:imageHeight
        % 黒の縞模様を適用
        img1(row:min(row+stripeWidth-1, imageHeight), :, :) = 0;
    end
    
    % Axesに画像を表示
    imshow(img1, 'Parent', app.UIAxes3);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.Visible = 'off';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @load, true);
            app.loadButton.Text = 'load';
            app.computeButton = uibutton(app.UIFigure, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @compute, true);
            app.computeButton.Text = 'compute';
            app.originalButton = uibutton(app.UIFigure, 'push');
            app.originalButton.ButtonPushedFcn = createCallbackFcn(app, @original, true);
            app.originalButton.Text = 'original';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end